classdef Orbitals_Project_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        MarkerSizeSlider          matlab.ui.control.Slider
        MarkerSizeSliderLabel     matlab.ui.control.Label
        EndDateDatePicker         matlab.ui.control.DatePicker
        EndDateDatePickerLabel    matlab.ui.control.Label
        StartDateDatePicker       matlab.ui.control.DatePicker
        StartDateDatePickerLabel  matlab.ui.control.Label
        PlottypeButtonGroup       matlab.ui.container.ButtonGroup
        DButton_2                 matlab.ui.control.ToggleButton
        SpaceTrackButton          matlab.ui.control.ToggleButton
        GroundTrackButton         matlab.ui.control.ToggleButton
        DButton                   matlab.ui.control.ToggleButton
        EnterButton               matlab.ui.control.Button
        TLEFiletxtTextArea        matlab.ui.control.TextArea
        TLEFiletxtTextAreaLabel   matlab.ui.control.Label
        TimeStepsTextArea         matlab.ui.control.TextArea
        TimeStepsTextAreaLabel    matlab.ui.control.Label
        PlotOptionsLabel          matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Satalite;
        Orbit;
        EarthMap;
        Time_Initial;
        Time_Final;
        Time_Step;
    end
    methods (Access = public)
        function GetOrbit(app)
            mu = 398600;
            Re = 6378 ;
            j2 = 1.08263e-3;
            t_epoch_tp = app.Satalite.MeanAnomaly/app.Satalite.MeanMotion;
            tp = app.Satalite.EpochTime - t_epoch_tp;
            n_t = (app.Time_Final-app.Satalite.EpochTime)/app.Time_Step;
            app.Orbit.Time = linspace(app.Satalite.EpochTime, app.Time_Final, n_t);
            E = zeros(size(app.Orbit.Time));
            for j = 1:length(app.Orbit.Time)
                M = app.Satalite.MeanMotion*(app.Orbit.Time(j)-tp);
                kepler = @(E_val) E_val - app.Satalite.Eccentricity*sin(E_val) - M;
                E(j) = fzero(kepler,M);
            end
            nu = 2.*atan2(sqrt(1+app.Satalite.Eccentricity).*sin(E/2),sqrt(1-app.Satalite.Eccentricity)*cos(E/2));
            r = app.Satalite.SMA.*(1-app.Satalite.Eccentricity^2)./(1+app.Satalite.Eccentricity.*cos(nu));
            app.Orbit.Rp = [r.*cos(nu); r.*sin(nu); zeros(size(app.Orbit.Time))];
            
            Omegadot = -(3/2*sqrt(mu)*j2*Re^2/(1-app.Satalite.Eccentricity^2)^2/app.Satalite.SMA^(7/2))*cos(app.Satalite.Inclinaton);
            omegadot = -(3/2*sqrt(mu)*j2*Re^2/(1-app.Satalite.Eccentricity^2)^2/app.Satalite.SMA^(7/2))*(5/2*sin(app.Satalite.Inclinaton)^2-2);
            app.Orbit.RAAN = Omegadot*(app.Orbit.Time-app.Satalite.EpochTime) + app.Satalite.RAAN;
            app.Orbit.AoP = omegadot*(app.Orbit.Time-app.Satalite.EpochTime) +app.Satalite.AoP;
            app.Orbit.R = zeros(3,length(app.Orbit.Time));
            for j = 1:length(app.Orbit.Time)
                T3O = [cos(app.Orbit.RAAN(j)), sin(app.Orbit.RAAN(j)), 0; ...
                    -sin(app.Orbit.RAAN(j)), cos(app.Orbit.RAAN(j)), 0; ...
                    0               0           1;];
                T1i = [ 1,   0,      0; ...
                    0,  cos(app.Satalite.Inclinaton), sin(app.Satalite.Inclinaton);....
                    0,  -sin(app.Satalite.Inclinaton), cos(app.Satalite.Inclinaton);];
            
                T3o = [cos(app.Orbit.AoP(j)), sin(app.Orbit.AoP(j)), 0; ...
                    -sin(app.Orbit.AoP(j)), cos(app.Orbit.AoP(j)), 0; ...
                    0               0           1;];
                Tip = (T3o * T1i * T3O).';
                app.Orbit.R(:,j) = Tip * app.Orbit.Rp(:,j);
            end
            theta = gmst_from_jd(app.Orbit.Time/24/60/60);
            app.Orbit.Rx =  app.Orbit.R(1,:).*cos(theta) + app.Orbit.R(2,:).*sin(theta);
            app.Orbit.Ry = -app.Orbit.R(1,:).*sin(theta) + app.Orbit.R(2,:).*cos(theta);
            app.Orbit.Rz =  app.Orbit.R(3,:);
            gamma = atan2(app.Orbit.Ry, app.Orbit.Rx);
            phi    = atan2(app.Orbit.Rz, sqrt(app.Orbit.Rx.^2 + app.Orbit.Ry.^2));
            app.Orbit.Gamma = rad2deg(gamma);
            app.Orbit.Phi    = rad2deg(phi);
            app.Orbit.Rnorm = sqrt(app.Orbit.Rx.^2 + app.Orbit.Ry.^2 + app.Orbit.Rz.^2);
            app.Orbit.Gx = Re * (app.Orbit.Rx ./ app.Orbit.Rnorm);
            app.Orbit.Gy = Re * (app.Orbit.Ry ./ app.Orbit.Rnorm);
            app.Orbit.Gz = Re * (app.Orbit.Rz ./ app.Orbit.Rnorm);
            function theta = gmst_from_jd(JD)
                T = (JD - 2451545.0)/36525;
                theta = 280.46061837 ...
                      + 360.98564736629 * (JD - 2451545.0) ...
                      + 0.000387933 * T.^2 ...
                      - (T.^3)/38710000;
                theta = deg2rad(mod(theta, 360));
            end
            [~, Start_Index] = min(abs(app.Orbit.Time - app.Time_Initial));
            app.Orbit.Time  = app.Orbit.Time(Start_Index:end);
            app.Orbit.Rp    = app.Orbit.Rp(:, Start_Index:end);
            app.Orbit.Rx    = app.Orbit.Rx(Start_Index:end);
            app.Orbit.Ry    = app.Orbit.Ry(Start_Index:end);
            app.Orbit.Rz    = app.Orbit.Rz(Start_Index:end);
            app.Orbit.Gamma = app.Orbit.Gamma(Start_Index:end);
            app.Orbit.Phi   = app.Orbit.Phi(Start_Index:end);
            app.Orbit.Gx    = app.Orbit.Gx(Start_Index:end);
            app.Orbit.Gy    = app.Orbit.Gy(Start_Index:end);
            app.Orbit.Gz    = app.Orbit.Gz(Start_Index:end);
        end
        function ParseTLE(app)
            mu = 398600;
            Orbit_file_lines = splitlines(app.Satalite.File);
            app.Satalite.Name = strtrim(Orbit_file_lines{1});
            Orbit_TLE_1 = Orbit_file_lines{2};
            Orbit_TLE_2 = Orbit_file_lines{3};
            inc_deg   = str2double(Orbit_TLE_2(9:16));
            raan_deg  = str2double(Orbit_TLE_2(18:25));
            ecc_raw   = str2double(Orbit_TLE_2(27:33));
            aop_deg   = str2double(Orbit_TLE_2(35:42));
            M_deg     = str2double(Orbit_TLE_2(44:51));
            n_revday  = str2double(Orbit_TLE_2(53:63));
            app.Satalite.Inclinaton   = deg2rad(inc_deg);
            app.Satalite.RAAN         = deg2rad(raan_deg);
            app.Satalite.Eccentricity = ecc_raw * 1e-7;
            app.Satalite.AoP          = deg2rad(aop_deg);
            app.Satalite.MeanAnomaly  = deg2rad(M_deg);
            n_rad = 2 * pi * n_revday;
            app.Satalite.MeanMotion = n_rad / 86400;
            app.Satalite.SMA = (mu / app.Satalite.MeanMotion^2)^(1/3);
            app.Satalite.PeriodicTime = 2 * pi / app.Satalite.MeanMotion;
            epoch_str = Orbit_TLE_1(19:32);
            YY = str2double(epoch_str(1:2));
            if YY >= 57
                epoch_year = 1900 + YY;
            else
                epoch_year = 2000 + YY;
            end
            day_str = epoch_str(3:end);
            day_val = str2double(day_str);
            day_int  = floor(day_val);
            day_frac = day_val - day_int;
            app.Satalite.EpochDate = datetime(epoch_year,1,1,0,0,0,'TimeZone','UTC') + days(day_int - 1) + seconds(day_frac * 86400);
            app.Satalite.EpochJulianDate = juliandate(app.Satalite.EpochDate);
            app.Satalite.EpochTime = app.Satalite.EpochJulianDate * 24 * 60 * 60;
            disp(app.Satalite.EpochTime)
        end
        function ClearPlot(app)
            cla(app.UIAxes);
            hold(app.UIAxes, 'off');
            if ~isempty(app.UIAxes.Legend)
                delete(app.UIAxes.Legend);
            end
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            app.UIAxes.ZLimMode = 'auto';
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes.XTickLabelMode = 'auto';
            app.UIAxes.YTickLabelMode = 'auto';
            app.UIAxes.ZTickLabelMode = 'auto';
            app.UIAxes.Title.String = '';
            app.UIAxes.XLabel.String = '';
            app.UIAxes.YLabel.String = '';
            app.UIAxes.ZLabel.String = '';
            grid(app.UIAxes,'off');
        end
        function PlotEarth2D(app)
            lon = linspace(-180, 180, size(app.EarthMap.jpg, 2));
            lat = linspace(-90, 90, size(app.EarthMap.jpg, 1));
            imagesc(app.UIAxes, lon, lat, app.EarthMap.jpg);
        end
        function PlotEarth3D(app)
            [x, y, z] = sphere(50);
            R = 6371;
            x = R * x;
            y = R * y;
            z = R * z;
            surf(app.UIAxes, x, y, z, 'FaceColor', 'texturemap', ...
                'EdgeColor', 'none', 'CData', app.EarthMap.jpg);
        end 
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('coastlines.mat', 'coastlon', 'coastlat');
            app.EarthMap.jpg = flipud(imread('C:\Users\Ezzed\Documents\GitHub\Orbitals_Project\Assets\Earth_Map3D.jpg'));
            app.EarthMap.Long = coastlon;
            app.EarthMap.Lat = coastlat;
            app.PlottypeButtonGroup.SelectedObject = app.GroundTrackButton;
        end

        % Value changed function: StartDateDatePicker
        function StartDateDatePickerValueChanged(app, event)
            app.Time_Initial = juliandate(app.StartDateDatePicker.Value) * 24 * 60 * 60;
            app.EndDateDatePicker.Value = max(app.StartDateDatePicker.Value + days(1), app.EndDateDatePicker.Value);
            app.Time_Final = juliandate(app.EndDateDatePicker.Value) * 24 * 60 * 60;
            app.EndDateDatePicker.Limits = [app.StartDateDatePicker.Value + days(1), app.StartDateDatePicker.Value + years(1000)];
            app.GetOrbit();
            app.PlottypeButtonGroupSelectionChanged();
        end

        % Value changed function: EndDateDatePicker
        function EndDateDatePickerValueChanged(app, event)
            app.Time_Final = juliandate(app.EndDateDatePicker.Value) * 24 * 60 * 60;
            app.GetOrbit();
            app.PlottypeButtonGroupSelectionChanged();
        end

        % Value changed function: TimeStepsTextArea
        function TimeStepsTextAreaValueChanged(app, event)
            app.Time_Step = str2double(strtrim(strjoin(string(app.TimeStepsTextArea.Value))));
            app.GetOrbit();
            app.PlottypeButtonGroupSelectionChanged();
        end

        % Selection changed function: PlottypeButtonGroup
        function PlottypeButtonGroupSelectionChanged(app, event)
            Plot_Type = app.PlottypeButtonGroup.SelectedObject;
            switch Plot_Type
                case app.DButton
                    app.ClearPlot();
                    view(app.UIAxes, 2);
                    view(app.UIAxes, 2);
                    hold(app.UIAxes, 'on');
                    grid(app.UIAxes, 'on');
                    plot(app.UIAxes, app.Orbit.Rp(1,:), app.Orbit.Rp(2,:), '.', 'MarkerSize', app.MarkerSizeSlider.Value);
                    xmax = 1.2 * max(app.Orbit.Rp(1,:));
                    ymax = 1.2 * max(app.Orbit.Rp(2,:));
                    xlim(app.UIAxes, [-xmax xmax]);
                    ylim(app.UIAxes, [-ymax ymax]);
                    app.UIAxes.XTickMode = 'auto';
                    app.UIAxes.YTickMode = 'auto';
                    xlabel(app.UIAxes, 'X (km)');
                    ylabel(app.UIAxes, 'Y (km)');
                    title(app.UIAxes, app.Satalite.Name);
                case app.DButton_2
                    app.ClearPlot();
                    view(app.UIAxes, 3);
                    hold(app.UIAxes, 'on');
                    grid(app.UIAxes, 'on');
                    axis(app.UIAxes, 'equal');
                    plot3(app.UIAxes, app.Orbit.Rx, app.Orbit.Ry, app.Orbit.Rz, '.', 'MarkerSize', app.MarkerSizeSlider.Value);
                    lim = max([6378*1.2, max(abs([app.Orbit.Rx app.Orbit.Ry app.Orbit.Rz]))]);
                    xlim(app.UIAxes, [-lim lim]);
                    ylim(app.UIAxes, [-lim lim]);
                    zlim(app.UIAxes, [-lim lim]);
                    app.UIAxes.XTickMode = 'auto';
                    app.UIAxes.YTickMode = 'auto';
                    app.UIAxes.ZTickMode = 'auto';
                    xlabel(app.UIAxes, 'X (km)');
                    ylabel(app.UIAxes, 'Y (km)');
                    zlabel(app.UIAxes, 'Z (km)');
                    title(app.UIAxes, app.Satalite.Name);
                case app.GroundTrackButton
                    app.ClearPlot();
                    view(app.UIAxes, 2);
                    hold(app.UIAxes, 'on');
                    grid(app.UIAxes, 'on');
                    app.PlotEarth2D();
                    plot(app.UIAxes, app.Orbit.Gamma, app.Orbit.Phi, '.', 'MarkerSize', app.MarkerSizeSlider.Value);
                    xlim(app.UIAxes, [-180, 180]);
                    ylim(app.UIAxes, [-90, 90])
                    app.UIAxes.XTick = -180:30:180;
                    app.UIAxes.YTick = -90:15:90;
                    app.UIAxes.XTickLabel = -180:30:180;
                    app.UIAxes.YTickLabel = -90:15:90;
                    xlabel(app.UIAxes, 'long (deg)');
                    ylabel(app.UIAxes, 'lat (deg)');
                    title(app.UIAxes, app.Satalite.Name);
                case app.SpaceTrackButton
                    app.ClearPlot();
                    view(app.UIAxes, 3);
                    hold(app.UIAxes, 'on');
                    grid(app.UIAxes, 'on');
                    axis(app.UIAxes, 'equal');
                    app.PlotEarth3D();
                    plot3(app.UIAxes, app.Orbit.Rx, app.Orbit.Ry, app.Orbit.Rz, '.', 'MarkerSize', app.MarkerSizeSlider.Value);
                    plot3(app.UIAxes, app.Orbit.Gx, app.Orbit.Gy, app.Orbit.Gz, '.', 'MarkerSize', app.MarkerSizeSlider.Value);
                    lim = max([6378*1.2, max(abs([app.Orbit.Rx app.Orbit.Ry app.Orbit.Rz]))]);
                    xlim(app.UIAxes, [-lim lim]);
                    ylim(app.UIAxes, [-lim lim]);
                    zlim(app.UIAxes, [-lim lim]);
                    app.UIAxes.XTickMode = 'auto';
                    app.UIAxes.YTickMode = 'auto';
                    app.UIAxes.ZTickMode = 'auto';
                    xlabel(app.UIAxes, 'X (km)');
                    ylabel(app.UIAxes, 'Y (km)');
                    zlabel(app.UIAxes, 'Z (km)');
                    title(app.UIAxes, app.Satalite.Name);
            end
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            app.Satalite.FilePath = strip(string(app.TLEFiletxtTextArea.Value), '"');
            app.Satalite.File = fileread(app.Satalite.FilePath);
            app.ParseTLE();
            app.Time_Initial = app.Satalite.EpochTime;
            app.Time_Step = 360;
            app.Time_Final = app.Satalite.EpochTime + 24 * 60 * 60;
            app.GetOrbit();
            app.PlottypeButtonGroupSelectionChanged();
            app.StartDateDatePicker.Value = app.Satalite.EpochDate;
            app.StartDateDatePicker.Limits = [app.Satalite.EpochDate, app.Satalite.EpochDate + years(1000)];
            app.EndDateDatePicker.Limits = [app.StartDateDatePicker.Value + days(1), app.StartDateDatePicker.Limits(2) + years(1000)];
            app.EndDateDatePicker.Value = app.StartDateDatePicker.Value + days(1);
        end

        % Value changed function: MarkerSizeSlider
        function MarkerSizeSliderValueChanged(app, event)
            app.PlottypeButtonGroupSelectionChanged();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PlotOptionsLabel = uilabel(app.GridLayout);
            app.PlotOptionsLabel.Text = 'Plot Options';
            app.TimeStepsTextAreaLabel = uilabel(app.GridLayout);
            app.TimeStepsTextAreaLabel.Text = 'Time Step(s)';
            app.TimeStepsTextArea = uitextarea(app.GridLayout);
            app.TimeStepsTextArea.ValueChangedFcn = createCallbackFcn(app, @TimeStepsTextAreaValueChanged, true);
            app.TLEFiletxtTextAreaLabel = uilabel(app.GridLayout);
            app.TLEFiletxtTextAreaLabel.Text = 'TLE File (.txt)';
            app.TLEFiletxtTextArea = uitextarea(app.GridLayout);
            app.EnterButton = uibutton(app.GridLayout, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.PlottypeButtonGroup = uibuttongroup(app.GridLayout);
            app.PlottypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlottypeButtonGroupSelectionChanged, true);
            app.PlottypeButtonGroup.Title = 'Plot type';
            app.DButton = uitogglebutton(app.PlottypeButtonGroup);
            app.DButton.Text = '2D';
            app.DButton.Value = true;
            app.GroundTrackButton = uitogglebutton(app.PlottypeButtonGroup);
            app.GroundTrackButton.Text = 'Ground Track';
            app.SpaceTrackButton = uitogglebutton(app.PlottypeButtonGroup);
            app.SpaceTrackButton.Text = 'Space Track';
            app.DButton_2 = uitogglebutton(app.PlottypeButtonGroup);
            app.DButton_2.Text = '3D';
            app.StartDateDatePickerLabel = uilabel(app.GridLayout);
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDateDatePicker = uidatepicker(app.GridLayout);
            app.StartDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @StartDateDatePickerValueChanged, true);
            app.EndDateDatePickerLabel = uilabel(app.GridLayout);
            app.EndDateDatePickerLabel.Text = 'End Date';
            app.EndDateDatePicker = uidatepicker(app.GridLayout);
            app.EndDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @EndDateDatePickerValueChanged, true);
            app.MarkerSizeSliderLabel = uilabel(app.GridLayout);
            app.MarkerSizeSliderLabel.Text = 'Marker Size';
            app.MarkerSizeSlider = uislider(app.GridLayout);
            app.MarkerSizeSlider.Limits = [1 10];
            app.MarkerSizeSlider.ValueChangedFcn = createCallbackFcn(app, @MarkerSizeSliderValueChanged, true);
            app.MarkerSizeSlider.Step = 0.5;
            app.MarkerSizeSlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Orbitals_Project_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end