classdef Orbitals_Project_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PlotUpdateButton                matlab.ui.control.Button
        InputLabel                      matlab.ui.control.Label
        V_zTextArea                     matlab.ui.control.TextArea
        V_zTextAreaLabel                matlab.ui.control.Label
        V_yTextArea                     matlab.ui.control.TextArea
        V_yTextAreaLabel                matlab.ui.control.Label
        V_xTextArea                     matlab.ui.control.TextArea
        V_xTextAreaLabel                matlab.ui.control.Label
        R_zTextArea                     matlab.ui.control.TextArea
        R_zTextAreaLabel                matlab.ui.control.Label
        R_yTextArea                     matlab.ui.control.TextArea
        R_yTextAreaLabel                matlab.ui.control.Label
        R_xTextArea                     matlab.ui.control.TextArea
        R_xTextAreaLabel                matlab.ui.control.Label
        OperationModeButtonGroup        matlab.ui.container.ButtonGroup
        RVButton                        matlab.ui.control.ToggleButton
        ParametricButton                matlab.ui.control.ToggleButton
        TLEButton                       matlab.ui.control.ToggleButton
        TLEtxtButton                    matlab.ui.control.ToggleButton
        TrueAnomalyTextArea             matlab.ui.control.TextArea
        TrueAnomalyTextAreaLabel        matlab.ui.control.Label
        InclinationTextArea             matlab.ui.control.TextArea
        InclinationTextAreaLabel        matlab.ui.control.Label
        ArgumentOfPerigeeTextArea       matlab.ui.control.TextArea
        ArgumentOfPerigeeTextAreaLabel  matlab.ui.control.Label
        RAANTextArea                    matlab.ui.control.TextArea
        RAANTextAreaLabel               matlab.ui.control.Label
        EccentricityTextArea            matlab.ui.control.TextArea
        EccentricityTextAreaLabel       matlab.ui.control.Label
        SemiMajorAxisTextArea           matlab.ui.control.TextArea
        SemiMajorAxisTextAreaLabel      matlab.ui.control.Label
        DatePicker                      matlab.ui.control.DatePicker
        DatePickerLabel                 matlab.ui.control.Label
        Slider                          matlab.ui.control.RangeSlider
        SliderLabel                     matlab.ui.control.Label
        TLEFormatTextArea               matlab.ui.control.TextArea
        EnterButton                     matlab.ui.control.Button
        TrackingTypeButtonGroup         matlab.ui.container.ButtonGroup
        Orbitin2DButton                 matlab.ui.control.ToggleButton
        SpaceTrackButton                matlab.ui.control.ToggleButton
        GroundTrackButton               matlab.ui.control.ToggleButton
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Satalite_TLE
        Satalite
        TLE_Text
    end
    
    methods (Access = private)
        function Create_Orbit(app)
            RE = 6738;
            mu = 398600;
            j2 = 1.08263e-3;
            n = app.Satalite.Mean_Motion * 2 * pi  / 86400;
            a = (mu / n^2)^(1/3);
            app.Satalite.Semi_Major_Axis = a;
            e = app.Satalite.Eccentricity;
            True_Anomaly = 2*atan(sqrt((1+e)/(1-e)) * tan(app.Satalite.Mean_Anomaly/2));
            RAAN = app.Satalite.RAAN;
            Inclination = app.Satalite.Inclination;
            Argument_Of_Perigee = app.Satalite.Argument_Of_Perigee;
            P = a * (1 - e^2);
            T = 2 * pi  / n;
            E_True_Anomaly = 2 * atan( sqrt((1 - e) / (1 + e)) * tan(True_Anomaly / 2) );
            t_True_Anomaly = (E_True_Anomaly - e * sin(E_True_Anomaly)) / n;
            N = 500;
            t_Vec = linspace(0, 2 * T, N);
            E_Vec = zeros(1, N);
            app.Satalite.RAAN_Vec = zeros(1, N);
            app.Satalite.Argument_Of_Perigee_Vec = zeros(1, N);
            app.Satalite.r_Vec = zeros(1, N);
            app.Satalite.rx_vec = zeros(1, N);
            app.Satalite.ry_vec = zeros(1, N);
            app.Satalite.rz_vec = zeros(1, N);
            app.Satalite.v_Vec = zeros(1, N);
            app.Satalite.vx_vec = zeros(1, N);
            app.Satalite.vy_vec = zeros(1, N);
            app.Satalite.vz_vec = zeros(1, N);
            app.Satalite.r_Inertial_Vec = zeros(3, N);
            app.Satalite.v_Inertial_Vec = zeros(3, N);
            DCMbi_vec = cell(1,N);
            for i = 1:N
                if i == 1
                    E_Vec(i) = fzero(@(E) getEQN(E, t_Vec(i), t_True_Anomaly, e, n), 0);
                else
                    E_Vec(i) = fzero(@(E) getEQN(E, t_Vec(i), t_True_Anomaly, e, n), E_Vec(i - 1));
                end
                app.Satalite.True_Anomaly_Vec(i) = 2 * atan( sqrt((1+e)/(1-e)) * tan(E_Vec(i)/2));
            end
            function EQN_out = getEQN(E, t_1, t_0, e, n)
                EQN_out = E - e * sin(E) - n * (t_1 - t_0);
            end
            RAAN_dot = -(3/2) * n * j2 * (RE/P)^2 * cos(Inclination);
            Argument_Of_Perigee_dot = (3/4) * n * j2 * (RE/P)^2 * (4 - 5*sin(Inclination)^2);
            Omega_Earth = 7.2921e-5;
            for i = 1:N
                app.Satalite.RAAN_Vec(i) = RAAN + RAAN_dot * t_Vec(i) - Omega_Earth * t_Vec(i);
                app.Satalite.Argument_Of_Perigee_Vec(i) = Argument_Of_Perigee + Argument_Of_Perigee_dot * t_Vec(i);
                app.Satalite.Inclination_Vec(i) = Inclination;
                RA = app.Satalite.RAAN_Vec(i);
                AP = app.Satalite.Argument_Of_Perigee_Vec(i);
                IN = app.Satalite.Inclination_Vec(i);
                DCMbi_vec{i} = [ cos(RA)*cos(AP) - sin(RA)*sin(AP)*cos(IN), -cos(RA)*sin(AP) - sin(RA)*cos(AP)*cos(IN), sin(RA)*sin(IN);
                    sin(RA)*cos(AP) + cos(RA)*sin(AP)*cos(IN), -sin(RA)*sin(AP) + cos(RA)*cos(AP)*cos(IN), -cos(RA)*sin(IN);
                    sin(AP)*sin(IN), cos(AP)*sin(IN), cos(IN) ];
                app.Satalite.r_Vec(i) = a*(1-e^2)/(1 + e*cos(app.Satalite.True_Anomaly_Vec(i)));
                app.Satalite.rx_vec(i) = app.Satalite.r_Vec(i) * cos(app.Satalite.True_Anomaly_Vec(i));
                app.Satalite.ry_vec(i) = app.Satalite.r_Vec(i) * sin(app.Satalite.True_Anomaly_Vec(i));
                app.Satalite.rz_vec(i) = 0;
                app.Satalite.v_Vec(i) = sqrt(mu*(2/app.Satalite.r_Vec(i) - 1/a));
                app.Satalite.vx_vec(i) = -sqrt(mu/P) * sin(app.Satalite.True_Anomaly_Vec(i));
                app.Satalite.vy_vec(i) = sqrt(mu/P) * cos(app.Satalite.True_Anomaly_Vec(i));
                app.Satalite.vz_vec(i) = 0;
                app.Satalite.r_Inertial_Vec(1:3, i) = DCMbi_vec{i} * [app.Satalite.rx_vec(i); app.Satalite.ry_vec(i); app.Satalite.rz_vec(i)];
                app.Satalite.v_Inertial_Vec(1:3, i) = DCMbi_vec{i} * [app.Satalite.vx_vec(i); app.Satalite.vy_vec(i); app.Satalite.vz_vec(i)];
            end
            app.Satalite.rx_Inertial_Vec = app.Satalite.r_Inertial_Vec(1,:);
            app.Satalite.ry_Inertial_Vec = app.Satalite.r_Inertial_Vec(2,:);
            app.Satalite.rz_Inertial_Vec = app.Satalite.r_Inertial_Vec(3,:);
            app.Satalite.vx_Inertial_Vec = app.Satalite.v_Inertial_Vec(1,:);
            app.Satalite.vy_Inertial_Vec = app.Satalite.v_Inertial_Vec(2,:);
            app.Satalite.vz_Inertial_Vec = app.Satalite.v_Inertial_Vec(3,:);
            app.Satalite.R_Inertial_Vec = sqrt(app.Satalite.rx_Inertial_Vec.^2 + app.Satalite.ry_Inertial_Vec.^2 + app.Satalite.rz_Inertial_Vec.^2);
            app.Satalite.V_Inertial_Vec = sqrt(app.Satalite.vx_Inertial_Vec.^2 + app.Satalite.vy_Inertial_Vec.^2 + app.Satalite.vz_Inertial_Vec.^2);
            app.Satalite.Phi_Vec = asin(app.Satalite.rz_Inertial_Vec ./ app.Satalite.R_Inertial_Vec);
            app.Satalite.Gamma_Vec = atan2(app.Satalite.ry_Inertial_Vec, app.Satalite.rx_Inertial_Vec);
            app.Update_Parameters();
        end
        function Parse_TLE(app)
            TLE_Line_0 = app.TLE_Text{1};
            TLE_Line_1 = app.TLE_Text{2};
            TLE_Line_2 = app.TLE_Text{3};
            app.Satalite_TLE.Satalite_Name = TLE_Line_0;

            app.Satalite_TLE.Satellite_Number_1 = TLE_Line_1(3:7);
            app.Satalite_TLE.Classification = TLE_Line_1(8);
            app.Satalite_TLE.International_Designator = TLE_Line_1(10:17);
            app.Satalite_TLE.Epoch_Year = TLE_Line_1(19:20);
            app.Satalite_TLE.Epoch_Day = TLE_Line_1(21:32);
            app.Satalite_TLE.Mean_Motion_Dot = TLE_Line_1(34:43);
            app.Satalite_TLE.Mean_Motion_Dot_Dot = TLE_Line_1(45:52);
            app.Satalite_TLE.BSTAR_Drag_Term = TLE_Line_1(54:61);
            app.Satalite_TLE.Ephemeris_Type = TLE_Line_1(63);
            app.Satalite_TLE.Element_Set_Number = TLE_Line_1(65:68);
            app.Satalite_TLE.Checksum_1 = TLE_Line_1(69);

            app.Satalite_TLE.Satellite_Number_2 = TLE_Line_2(3:7);
            app.Satalite_TLE.Inclination = TLE_Line_2(9:16);
            app.Satalite_TLE.RAAN = TLE_Line_2(18:25);
            app.Satalite_TLE.Eccentricity = TLE_Line_2(27:33);
            app.Satalite_TLE.Argument_Of_Perigee = TLE_Line_2(35:42);
            app.Satalite_TLE.Mean_Anomaly = TLE_Line_2(44:51);
            app.Satalite_TLE.Mean_Motion = TLE_Line_2(53:63);
            app.Satalite_TLE.Revolution_Number = TLE_Line_2(64:68);
            app.Satalite_TLE.Checksum_2 = TLE_Line_2(69);

            app.Satalite.Name = strtrim(app.Satalite_TLE.Satalite_Name);
            app.Satalite.Epoch_Date = Parse_Epoch_Date(app.Satalite_TLE.Epoch_Year, app.Satalite_TLE.Epoch_Day);
            app.Satalite.Epoch_Time_Zone = 'UTC';
            app.Slider_Update(app.Satalite.Epoch_Date);
            app.Satalite.Inclination = deg2rad(str2double(app.Satalite_TLE.Inclination));
            app.Satalite.RAAN = deg2rad(str2double(app.Satalite_TLE.RAAN));
            app.Satalite.Eccentricity = str2double(app.Satalite_TLE.Eccentricity) * 1e-7;
            app.Satalite.Argument_Of_Perigee = deg2rad(str2double(app.Satalite_TLE.Argument_Of_Perigee));
            app.Satalite.Mean_Anomaly = deg2rad(str2double(app.Satalite_TLE.Mean_Anomaly));
            app.Satalite.Mean_Motion = str2double(app.Satalite_TLE.Mean_Motion);
            function Epoch_Date = Parse_Epoch_Date(Epoch_Year_in, Epoch_Day_in)
                YY = str2double(Epoch_Year_in);
                DD = str2double(Epoch_Day_in);
                if YY < 57
                    YY = 2000 + YY;
                else
                    YY = 1900 + YY;
                end
                DD_int = floor(DD);
                DD_frac = DD - DD_int;
                SS = DD_frac * 86400;
                Epoch_Date = datetime(YY,1,1) + days(DD_int - 1) + seconds(SS);
            end
        end
        function Slider_Update(app, Epoch_Date_in)
                Epoch_Num = datenum(Epoch_Date_in);
                Slider_Min = Epoch_Num;
                Slider_Max = Slider_Min + 10;
                Slider_Tick_Values = linspace(Slider_Min, Slider_Max, 5);
                Slider_Tick_Labels = cellstr(string(datetime(Slider_Tick_Values, 'ConvertFrom', 'datenum'), 'yyyy-MM-dd'));
                app.Slider.MajorTicks = Slider_Tick_Values;
                app.Slider.MajorTickLabels = Slider_Tick_Labels;
        end
        function Reset_Axes_3D(app)
            cla(app.UIAxes, 'reset');
            view(app.UIAxes, 3);
            app.UIAxes.Projection = 'perspective';
            axis(app.UIAxes, 'equal');
            daspect(app.UIAxes, [1 1 1]);
            pbaspect(app.UIAxes, [1 1 1]);
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
        end
        function Reset_Axes_2D(app)
            cla(app.UIAxes, 'reset');
            view(app.UIAxes, 2);
            app.UIAxes.Projection = 'orthographic';
        end
        function Update_Parameters(app)
            Operation_Mode = app.OperationModeButtonGroup.SelectedObject;
            switch Operation_Mode
                case app.TLEtxtButton
                    app.TrueAnomalyTextArea.Value = num2str( rad2deg(app.Satalite.True_Anomaly_Vec(1)) );
                    app.InclinationTextArea.Value = num2str( rad2deg(app.Satalite.Inclination_Vec(1)) );
                    app.ArgumentOfPerigeeTextArea.Value = num2str( rad2deg(app.Satalite.Argument_Of_Perigee_Vec(1)) );
                    app.RAANTextArea.Value = num2str( rad2deg(app.Satalite.RAAN_Vec(1)) );
                    app.EccentricityTextArea.Value = num2str( app.Satalite.Eccentricity );
                    app.SemiMajorAxisTextArea.Value = num2str( app.Satalite.Semi_Major_Axis );
                    app.R_xTextArea.Value = num2str( app.Satalite.rx_Inertial_Vec(1) );
                    app.R_yTextArea.Value = num2str( app.Satalite.ry_Inertial_Vec(1) );
                    app.R_zTextArea.Value = num2str( app.Satalite.rz_Inertial_Vec(1) );
                    app.V_xTextArea.Value = num2str( app.Satalite.vx_Inertial_Vec(1) );
                    app.V_yTextArea.Value = num2str( app.Satalite.vy_Inertial_Vec(1) );
                    app.V_zTextArea.Value = num2str( app.Satalite.vz_Inertial_Vec(1) );
                case app.TLEButton
                    app.TrueAnomalyTextArea.Value = num2str( rad2deg(app.Satalite.True_Anomaly_Vec(1)) );
                    app.InclinationTextArea.Value = num2str( rad2deg(app.Satalite.Inclination_Vec(1)) );
                    app.ArgumentOfPerigeeTextArea.Value = num2str( rad2deg(app.Satalite.Argument_Of_Perigee_Vec(1)) );
                    app.RAANTextArea.Value = num2str( rad2deg(app.Satalite.RAAN_Vec(1)) );
                    app.EccentricityTextArea.Value = num2str( app.Satalite.Eccentricity );
                    app.SemiMajorAxisTextArea.Value = num2str( app.Satalite.Semi_Major_Axis );
                    app.R_xTextArea.Value = num2str( app.Satalite.rx_Inertial_Vec(1) );
                    app.R_yTextArea.Value = num2str( app.Satalite.ry_Inertial_Vec(1) );
                    app.R_zTextArea.Value = num2str( app.Satalite.rz_Inertial_Vec(1) );
                    app.V_xTextArea.Value = num2str( app.Satalite.vx_Inertial_Vec(1) );
                    app.V_yTextArea.Value = num2str( app.Satalite.vy_Inertial_Vec(1) );
                    app.V_zTextArea.Value = num2str( app.Satalite.vz_Inertial_Vec(1) );
                case app.ParametricButton
                    app.TrueAnomalyTextArea.Value = '';
                    app.InclinationTextArea.Value = '';
                    app.ArgumentOfPerigeeTextArea.Value = '';
                    app.RAANTextArea.Value = '';
                    app.EccentricityTextArea.Value = '';
                    app.SemiMajorAxisTextArea.Value = '';
                    app.R_xTextArea.Value = num2str( app.Satalite.rx_Inertial_Vec(1) );
                    app.R_yTextArea.Value = num2str( app.Satalite.ry_Inertial_Vec(1) );
                    app.R_zTextArea.Value = num2str( app.Satalite.rz_Inertial_Vec(1) );
                    app.V_xTextArea.Value = num2str( app.Satalite.vx_Inertial_Vec(1) );
                    app.V_yTextArea.Value = num2str( app.Satalite.vy_Inertial_Vec(1) );
                    app.V_zTextArea.Value = num2str( app.Satalite.vz_Inertial_Vec(1) );
                case app.RVButton
                    app.TrueAnomalyTextArea.Value = num2str( rad2deg(app.Satalite.True_Anomaly_Vec(1)) );
                    app.InclinationTextArea.Value = num2str( rad2deg(app.Satalite.Inclination_Vec(1)) );
                    app.ArgumentOfPerigeeTextArea.Value = num2str( rad2deg(app.Satalite.Argument_Of_Perigee_Vec(1)) );
                    app.RAANTextArea.Value = num2str( rad2deg(app.Satalite.RAAN_Vec(1)) );
                    app.EccentricityTextArea.Value = num2str( app.Satalite.Eccentricity );
                    app.SemiMajorAxisTextArea.Value = num2str( app.Satalite.Semi_Major_Axis );
                    app.R_xTextArea.Value = '';
                    app.R_yTextArea.Value = '';
                    app.R_zTextArea.Value = '';
                    app.V_xTextArea.Value = '';
                    app.V_yTextArea.Value = '';
                    app.V_zTextArea.Value = ''; 
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Selection changed function: OperationModeButtonGroup
        function OperationModeButtonGroupSelectionChanged(app, event)
            Operation_Mode= app.OperationModeButtonGroup.SelectedObject;
            switch Operation_Mode
                case app.TLEtxtButton
                    app.TrueAnomalyTextArea.Editable = 'off';
                    app.InclinationTextArea.Editable = 'off';
                    app.ArgumentOfPerigeeTextArea.Editable = 'off';
                    app.RAANTextArea.Editable = 'off';
                    app.EccentricityTextArea.Editable = 'off';
                    app.SemiMajorAxisTextArea.Editable = 'off';
                    app.V_zTextArea.Editable = 'off';
                    app.V_yTextArea.Editable = 'off';
                    app.V_xTextArea.Editable = 'off';
                    app.R_zTextArea.Editable = 'off';
                    app.R_yTextArea.Editable = 'off';
                    app.R_xTextArea.Editable = 'off';
                case app.TLEButton
                    app.TrueAnomalyTextArea.Editable = 'off';
                    app.InclinationTextArea.Editable = 'off';
                    app.ArgumentOfPerigeeTextArea.Editable = 'off';
                    app.RAANTextArea.Editable = 'off';
                    app.EccentricityTextArea.Editable = 'off';
                    app.SemiMajorAxisTextArea.Editable = 'off';
                    app.V_zTextArea.Editable = 'off';
                    app.V_yTextArea.Editable = 'off';
                    app.V_xTextArea.Editable = 'off';
                    app.R_zTextArea.Editable = 'off';
                    app.R_yTextArea.Editable = 'off';
                    app.R_xTextArea.Editable = 'off';
                case app.ParametricButton
                    app.TrueAnomalyTextArea.Editable = 'on';
                    app.InclinationTextArea.Editable = 'on';
                    app.ArgumentOfPerigeeTextArea.Editable = 'on';
                    app.RAANTextArea.Editable = 'on';
                    app.EccentricityTextArea.Editable = 'on';
                    app.SemiMajorAxisTextArea.Editable = 'on';
                    app.V_zTextArea.Editable = 'off';
                    app.V_yTextArea.Editable = 'off';
                    app.V_xTextArea.Editable = 'off';
                    app.R_zTextArea.Editable = 'off';
                    app.R_yTextArea.Editable = 'off';
                    app.R_xTextArea.Editable = 'off';
                case app.RVButton
                    app.TrueAnomalyTextArea.Editable = 'off';
                    app.InclinationTextArea.Editable = 'off';
                    app.ArgumentOfPerigeeTextArea.Editable = 'off';
                    app.RAANTextArea.Editable = 'off';
                    app.EccentricityTextArea.Editable = 'off';
                    app.SemiMajorAxisTextArea.Editable = 'off';
                    app.V_zTextArea.Editable = 'on';
                    app.V_yTextArea.Editable = 'on';
                    app.V_xTextArea.Editable = 'on';
                    app.R_zTextArea.Editable = 'on';
                    app.R_yTextArea.Editable = 'on';
                    app.R_xTextArea.Editable = 'on';
            end
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            app.TLE_Text = app.TLEFormatTextArea.Value;
            app.Parse_TLE();
        end

        % Button pushed function: PlotUpdateButton
        function PlotUpdateButtonPushed(app, event)
            app.Create_Orbit();
            Tracking_Type = app.TrackingTypeButtonGroup.SelectedObject;
            switch Tracking_Type
                case app.GroundTrackButton
                    app.Reset_Axes_2D();
                    load('coastlines.mat', 'coastlon', 'coastlat');
                    plot(app.UIAxes, coastlon, coastlat, 'b');
                    xlim(app.UIAxes,[-180, 180]); ylim(app.UIAxes, [-90, 90]);
                    grid(app.UIAxes, 'on');
                    app.UIAxes.XTick = -180:30:180;
                    app.UIAxes.YTick = -90:15:90;
                    xlabel(app.UIAxes, 'Longitude (deg)'); ylabel(app.UIAxes, 'Latitude (deg)')
                case app.SpaceTrackButton
                    app.Reset_Axes_3D();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TrackingTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.TrackingTypeButtonGroup.Title = 'Tracking Type';
            app.GroundTrackButton = uitogglebutton(app.TrackingTypeButtonGroup);
            app.GroundTrackButton.Text = 'Ground Track';
            app.GroundTrackButton.Value = true;
            app.SpaceTrackButton = uitogglebutton(app.TrackingTypeButtonGroup);
            app.SpaceTrackButton.Text = 'Space Track';
            app.Orbitin2DButton = uitogglebutton(app.TrackingTypeButtonGroup);
            app.Orbitin2DButton.Text = 'Orbit in 2D';
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.TLEFormatTextArea = uitextarea(app.UIFigure);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure, 'range');
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.DatePickerLabel = uilabel(app.UIFigure);
            app.DatePickerLabel.Text = 'Date Picker';
            app.DatePicker = uidatepicker(app.UIFigure);
            app.SemiMajorAxisTextAreaLabel = uilabel(app.UIFigure);
            app.SemiMajorAxisTextAreaLabel.Text = 'Semi-Major Axis';
            app.SemiMajorAxisTextArea = uitextarea(app.UIFigure);
            app.EccentricityTextAreaLabel = uilabel(app.UIFigure);
            app.EccentricityTextAreaLabel.Text = 'Eccentricity';
            app.EccentricityTextArea = uitextarea(app.UIFigure);
            app.RAANTextAreaLabel = uilabel(app.UIFigure);
            app.RAANTextAreaLabel.Text = 'RAAN';
            app.RAANTextArea = uitextarea(app.UIFigure);
            app.ArgumentOfPerigeeTextAreaLabel = uilabel(app.UIFigure);
            app.ArgumentOfPerigeeTextAreaLabel.Text = 'Argument Of Perigee';
            app.ArgumentOfPerigeeTextArea = uitextarea(app.UIFigure);
            app.InclinationTextAreaLabel = uilabel(app.UIFigure);
            app.InclinationTextAreaLabel.Text = 'Inclination';
            app.InclinationTextArea = uitextarea(app.UIFigure);
            app.TrueAnomalyTextAreaLabel = uilabel(app.UIFigure);
            app.TrueAnomalyTextAreaLabel.Text = 'True Anomaly';
            app.TrueAnomalyTextArea = uitextarea(app.UIFigure);
            app.OperationModeButtonGroup = uibuttongroup(app.UIFigure);
            app.OperationModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OperationModeButtonGroupSelectionChanged, true);
            app.OperationModeButtonGroup.Title = 'Operation Mode';
            app.TLEtxtButton = uitogglebutton(app.OperationModeButtonGroup);
            app.TLEtxtButton.Text = 'TLE.txt';
            app.TLEtxtButton.Value = true;
            app.TLEButton = uitogglebutton(app.OperationModeButtonGroup);
            app.TLEButton.Text = 'TLE';
            app.ParametricButton = uitogglebutton(app.OperationModeButtonGroup);
            app.ParametricButton.Text = 'Parametric';
            app.RVButton = uitogglebutton(app.OperationModeButtonGroup);
            app.RVButton.Text = 'R-V';
            app.R_xTextAreaLabel = uilabel(app.UIFigure);
            app.R_xTextAreaLabel.Text = 'R_x';
            app.R_xTextArea = uitextarea(app.UIFigure);
            app.R_yTextAreaLabel = uilabel(app.UIFigure);
            app.R_yTextAreaLabel.Text = 'R_y';
            app.R_yTextArea = uitextarea(app.UIFigure);
            app.R_zTextAreaLabel = uilabel(app.UIFigure);
            app.R_zTextAreaLabel.Text = 'R_z';
            app.R_zTextArea = uitextarea(app.UIFigure);
            app.V_xTextAreaLabel = uilabel(app.UIFigure);
            app.V_xTextAreaLabel.Text = 'V_x';
            app.V_xTextArea = uitextarea(app.UIFigure);
            app.V_yTextAreaLabel = uilabel(app.UIFigure);
            app.V_yTextAreaLabel.Text = 'V_y';
            app.V_yTextArea = uitextarea(app.UIFigure);
            app.V_zTextAreaLabel = uilabel(app.UIFigure);
            app.V_zTextAreaLabel.Text = 'V_z';
            app.V_zTextArea = uitextarea(app.UIFigure);
            app.InputLabel = uilabel(app.UIFigure);
            app.InputLabel.Text = 'Input Label';
            app.PlotUpdateButton = uibutton(app.UIFigure, 'push');
            app.PlotUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @PlotUpdateButtonPushed, true);
            app.PlotUpdateButton.Text = 'Plot Update';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Orbitals_Project_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end